classdef ColorSeg < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        zoomLabel               matlab.ui.control.Label
        ZoomType                matlab.ui.control.Switch
        EditType                matlab.ui.control.Switch
        EditButton              matlab.ui.control.Button
        EditButtonGroup         matlab.ui.container.ButtonGroup
        ClrButton               matlab.ui.control.RadioButton
        ROIButton               matlab.ui.control.RadioButton
        ROItoShow               matlab.ui.control.Switch
        dilatevesCheckBox       matlab.ui.control.CheckBox
        MaxSpinner              matlab.ui.control.Spinner
        MaxSpinnerLabel         matlab.ui.control.Label
        MinSpinner              matlab.ui.control.Spinner
        MinSpinnerLabel         matlab.ui.control.Label
        ExportButton            matlab.ui.control.Button
        SaveButton              matlab.ui.control.Button
        ToanalyzeDropDown       matlab.ui.control.DropDown
        ToanalyzeDropDownLabel  matlab.ui.control.Label
        ProtocolDropDown        matlab.ui.control.DropDown
        ProtocolDropDownLabel   matlab.ui.control.Label
        ImagesListBoxLabel      matlab.ui.control.Label
        ClrclustSpinner         matlab.ui.control.Spinner
        ClrclustSpinnerLabel    matlab.ui.control.Label
        AnalysisButton          matlab.ui.control.Button
        ClustersSpinner         matlab.ui.control.Spinner
        ClustersSpinnerLabel    matlab.ui.control.Label
        ScaleSpinner            matlab.ui.control.Spinner
        ScaleSpinnerLabel       matlab.ui.control.Label
        ImagesListBox           matlab.ui.control.ListBox
        Label                   matlab.ui.control.Label
        LoadimagefolderButton   matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadimagefolderButton
        function LoadimagefolderButtonPushed(app, event)
%             app.NormalizedataCheckBox.Enable=false;
% app.Log1pCheckBox.Enable=false;
%             app.FeaturesListBox.Enable= false;  
%             app.cmap.Enable= false;  
try
            path=uigetdir(pwd,'Select Folder containing image Files');
            
extensions = {'*.tif', '*.png', '*.jpg', '*.jpeg'};
list = cellfun(@(ext) dir(fullfile(path, ext)), extensions, 'UniformOutput', false);
list = vertcat(list{:}); 

if isempty(list)
    opts = struct('WindowStyle','modal','Interpreter','tex');
warndlg(['\color{red} ' 'No image files were found for tif, png or jpg/jpeg'],'Reading file error', opts);
else
    app.Label.Text=path;

    if isfile([path filesep 'ColorSegResults.mat'])
        clear list
        load([path filesep 'ColorSegResults.mat'],'list','nScale')
        app.ImagesListBox.Items=list(:,1);
        app.ScaleSpinner.Value=nScale;
    else        
list= extractfield(list,'name')';
app.ImagesListBox.Items=list;
list(:,2:3)={[]};
    end
app.ImagesListBox.UserData=list;
% app.LoadVizgenfolderButton.UserData=hfig;


hfig=figure("CloseRequestFcn",'',"Position",[1 1 1519 646]); % 'closereq'
haxes1=subplot_tight(1,2,1,[0.065,0.04]); cla(haxes1) ; assignin('base','haxes1',haxes1)
haxes2=subplot_tight(1,2,2,[0.065,0.04]); cla(haxes2) ; assignin('base','haxes2',haxes2)

assignin('base','hfig',hfig)
assignin('base','app',app)
app.ImagesListBox.Value=list{1,1};
app.ImagesListBoxValueChanged(app);
end          

catch ME
   opts = struct('WindowStyle','modal','Interpreter','tex');
warndlg(['\color{red} ' ME.message],'Loading error', opts);
end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            close("all","force")
            
        end

        % Value changed function: ImagesListBox
        function ImagesListBoxValueChanged(app, event)
            value = app.ImagesListBox.Value;
            list=app.ImagesListBox.UserData;


figure(evalin('base', 'hfig')); 
haxes1=evalin('base', 'haxes1'); cla(haxes1) ; set(haxes1,'YLimMode','auto','XLimMode','auto')
haxes2=evalin('base', 'haxes2'); cla(haxes2) ; set(haxes2,'YLimMode','auto','XLimMode','auto')
idx=ismember(list(:,1),value);

img=im2uint8(imresize(imread([app.Label.Text filesep value]),app.ScaleSpinner.Value));
imshow(img,'Parent',haxes1); title(haxes1,value)

if ~isempty(list{idx,2})
% cla(haxes1),imshow(img,'Parent',haxes1)
% plot ROI
maskBounds = bwboundaries(full(list{idx,2})); % ROI
hold(haxes1,'on'), cellfun(@(x)(plot(haxes1,x(:,2),x(:,1),'y','LineWidth',1)),maskBounds);
% plot mask with limits
if strcmp(app.ROItoShow.Value,'In')
roi=full(list{idx,4}) & full(list{idx,2});
else
roi=~full(list{idx,4}) & full(list{idx,2});
end
imshow(img .* uint8(roi),'Parent',haxes2)
     ROI=full(list{idx,2});
title(num2str(bwarea(roi)*100/bwarea(ROI)));

app.MinSpinner.Limits= list{idx,5};
app.MaxSpinner.Limits= list{idx,5};

app.MinSpinner.Value=list{idx,7}(1);
app.MaxSpinner.Value=list{idx,7}(2);

end
        end

        % Value changed function: ClustersSpinner
        function ClustersSpinnerValueChanged(app, event)
            value = app.ClustersSpinner.Value;
            app.ClrclustSpinner.Limits=[1,value];
        end

        % Button pushed function: AnalysisButton
        function AnalysisButtonPushed(app, event)
           fig=uifigure('Position',[488 467 560 194]);
           fig_progress=uiprogressdlg(fig,'Title','Data analysis',...
        'Indeterminate','on');
           
           list=app.ImagesListBox.UserData;
% haxes1=evalin('base', 'haxes1'); haxes2=evalin('base', 'haxes2');
idx=1:numel(list(:,1));
if strcmp(app.ToanalyzeDropDown.Value,'Selected')
    idx=find(ismember(list(:,1),app.ImagesListBox.Value));
elseif strcmp(app.ToanalyzeDropDown.Value,'Selected onwards')
    idx=idx(find(ismember(list(:,1),app.ImagesListBox.Value)):end);
end


for i=idx
fig_progress.Message=['Reading ' list{i,1} ' image'];
img=im2uint8(imresize(imread([app.Label.Text filesep list{i}]),app.ScaleSpinner.Value));

fig_progress.Message=['Estimating ' list{i,1} ' ROI'];   
        % first estimate of ROI
        ROI=rgb2gray(img)>=multithresh(rgb2gray(img)); 
if bwarea(imfill(ROI,"holes"))>bwarea(imfill(~ROI,"holes")), ROI=~ROI; end
ROIholes=imfill(ROI,"holes")-ROI;

ROI=imfill(ROI,"holes");
BiggestArea=bwarea(bwareafilt(ROI,1));
ROI=bwareaopen(ROI,round((0.05)*BiggestArea)); % remove smaller parts by 5%
%smoothen
windowSize = 5;
kernel = ones(windowSize) / windowSize ^ 2;
blurryImage = conv2(single(full(ROI)), kernel, 'same');
ROI = blurryImage > 0.5; % Rethreshold
ROI=sparse(imdilate(ROI,strel('disk',2,8)));

% ROI=sparse(logical(ones(size(ROI))));
% ROI=sparse(imerode(imdilate(ROI,strel('disk',1,8)),strel('disk',1,8)));

% % process the holes (likely perivascular)
% ROIholes=bwlabel(bwareaopen(ROIholes,20)); 
% stats=extractfield(regionprops(logical(ROIholes),"Circularity"),'Circularity');
% keeperIdx=find(stats>=0.75);
% ROIholes = ismember(ROIholes, keeperIdx);

% %%% add ROI to image
% cla(haxes1),imshow(img,'Parent',haxes1)
% maskBounds = bwboundaries(full(ROI));
% hold(haxes1,'on'), cellfun(@(x)(plot(haxes1,x(:,2),x(:,1),'y','LineWidth',1)),maskBounds);
        if strcmp(app.ProtocolDropDown.Value,'Picrosirius Red')
            fig_progress.Message=['Estimating ' list{i,1} ' Picrosirius Red staining'];
            ['Estimating ' list{i,1} ' Picrosirius Red staining']
%% Get 'Picrosirius Red' (PSR)
% Convert Image to : Read your image and convert it from RGB to color space.
lab_img = rgb2lab(img);
%Extract Chromaticity Channels: Since color information lies in 'a' and 'b', ignore the 'L' channel to make the segmentation lighting-invariant.
ab = lab_img(:,:,2:3);
ab = im2single(ab); % Convert for clustering
%Perform Clustering (K-Means): Use the imsegkmeans function to partition the ' ' data into distinct color clusters.
numColors = app.ClustersSpinner.Value; % Number of colors to separate, 3 is optimal for 'Picrosirius Red'
pixel_labels = imsegkmeans(ab, numColors, 'NumAttempts', 3);

% Create Mask/Segmented Image: Use the resulting label matrix to create masks for each color.
% find red channel first for Picrosirius Red (should have lower area)
N=nan(1,app.ClustersSpinner.Value);
for j=1:app.ClustersSpinner.Value
N(j)=bwarea(pixel_labels == j);
end
idxPSR=find(N==min(N));
% mask = pixel_labels == idxPSR & full(ROI);
mask = pixel_labels == idxPSR & full(list{i,2});
stats=extractfield(regionprops(mask,"Area"),'Area');
minVal=round(min(stats));
maxVal=round(max(stats));

mask2 = bwareaopen(mask,app.MinSpinner.Value); 
% figure; imshow(img .* uint8(mask))

if app.dilatevesCheckBox.Value
%%% IF removinf perivescular stain
bw=logical(ROIholes);
bw=bwareaopen(bw,100); 
bw=bwmorph(bwmorph(bw,'thicken',3),'close'); %figure; imshow(bw)

% bw=bwlabel(bw); stats=extractfield(regionprops(bw,"Solidity"),'Solidity');
% keeperIdx = find(stats>0.7);% 
% bw = ismember(bw, keeperIdx); figure; imshow(bw)
% figure;histfit(stats',[],"kernel")

mask2=(mask2-bw)>0; % update mask2
mask=(mask-bw)>0;
end
% cla(haxes2),imshow(img .* uint8(mask),'Parent',haxes2)
% maskBounds = bwboundaries(full(ROI));
% hold(haxes1,'on'), cellfun(@(x)(plot(haxes1,x(:,2),x(:,1),'y','LineWidth',1)),maskBounds);

% maskedImage1 = img .* uint8(mask1);
% mask=logical(imdilate(mask,strel('disk',1,8)));
        end
list{i,2}=ROI;
list{i,3}=sparse(mask);% Origonal PSR mask
list{i,4}=sparse(mask2);% PSR after threshold
list{i,5}=[minVal,maxVal]; % limits of min max based on mask
list{i,6}=sparse(logical(ROIholes));
list{i,7}=[minVal,maxVal];% limits of min max based on mask2
    %% After protocol, update list
end
app.ImagesListBox.UserData=list;
app.ImagesListBoxValueChanged(app);
close(fig)
        end

        % Value changed function: MinSpinner
        function MinSpinnerValueChanged(app, event)
            list=app.ImagesListBox.UserData;
idx=ismember(list(:,1),app.ImagesListBox.Value);
if ~isempty(list{idx,2})
mask=full(list{idx,3});
% list{idx,4}=bwareaopen(mask,value);%update mask2
list{idx,4}=sparse(bwareafilt(mask, [app.MinSpinner.Value, app.MaxSpinner.Value]));

stats=extractfield(regionprops(full(list{idx,4}),"Area"),'Area');
minVal=round(min(stats));
maxVal=round(max(stats));
list{idx,7}=[minVal,maxVal];

app.ImagesListBox.UserData=list;
app.ImagesListBoxValueChanged(app);
end
        end

        % Value changed function: MaxSpinner
        function MaxSpinnerValueChanged(app, event)
           list=app.ImagesListBox.UserData;
idx=ismember(list(:,1),app.ImagesListBox.Value);
if ~isempty(list{idx,2})
mask=full(list{idx,3});
% list{idx,4}=bwareaopen(mask,value);%update mask2
list{idx,4}=sparse(bwareafilt(mask, [app.MinSpinner.Value, app.MaxSpinner.Value]));

stats=extractfield(regionprops(full(list{idx,4}),"Area"),'Area');
minVal=round(min(stats));
maxVal=round(max(stats));
list{idx,7}=[minVal,maxVal];

app.ImagesListBox.UserData=list;
app.ImagesListBoxValueChanged(app);
end
        end

        % Value changed function: ROItoShow
        function ROItoShowValueChanged(app, event)
app.ImagesListBoxValueChanged(app);
        end

        % Button pushed function: EditButton
        function EditButtonPushed(app, event)
list=app.ImagesListBox.UserData;
idx=ismember(list(:,1),app.ImagesListBox.Value);

haxes1=evalin('base', 'haxes1'); set(haxes1,'YLimMode','auto','XLimMode','auto')
haxes2=evalin('base', 'haxes2'); set(haxes2,'YLimMode','auto','XLimMode','auto')

 hmsg=msgbox('press Escape key when done');
uiwait(hmsg)

if app.ROIButton.Value
    BW=full(list{idx,2}); %ROI mask
try BW2=multiROIs(haxes1,1,'g');catch BW2=zeros(size(BW)); end
        if strcmp(app.EditType.Value,'+')
BW = sparse(logical(BW+BW2));
        else
BW = logical(BW)-logical(BW2); BW(BW<0)=0;BW=sparse(logical(BW));
        end
list{idx,2}=BW;


else
    BW=full(list{idx,4}); %Color of interest mask
% [xi,yi] = getpts(haxes2);%option 1
try BW2=multiROIs(haxes2,1,'g');catch BW2=zeros(size(BW)); end % option 2
[yi,xi] = find(BW2);

    BW2 = bwselect(BW,xi,yi,4);
BW = BW-BW2;
BW(BW<1)=0;
BW=sparse(BW);

list{idx,4}=BW;
end
app.ImagesListBox.UserData=list;
app.ImagesListBoxValueChanged(app);

        end

        % Selection changed function: EditButtonGroup
        function EditButtonGroupSelectionChanged(app, event)
            % selectedButton = app.EditButtonGroup.SelectedObject;
            if app.ClrButton.Value
            app.EditType.Value='-';    
            end
        end

        % Value changed function: EditType
        function EditTypeValueChanged(app, event)
if strcmp(app.EditType.Value,'+')
    app.ROIButton.Value=true;
end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            fig=uifigure('Position',[488 467 560 194]);
           fig_progress=uiprogressdlg(fig,'Title','Save status',...
        'Indeterminate','on');
           fig_progress.Message='Saving results';
           list=app.ImagesListBox.UserData;
           nScale=app.ScaleSpinner.Value;
save([app.Label.Text filesep 'ColorSegResults.mat'],"list","nScale","-v7.3")   
close(fig)
        end

        % Value changed function: dilatevesCheckBox
        function dilatevesCheckBoxValueChanged(app, event)
            try
                if app.dilatevesCheckBox.Value      
list=app.ImagesListBox.UserData;
idx=ismember(list(:,1),app.ImagesListBox.Value);
mask2=full(list{idx,4});
                bw=mask2;
bw=bwmorph(bw,'close',Inf); %figure; imshow(bw)
bw=bwareaopen(bw,2000);

mask2=logical(mask2+bw); % update mask2
            list{idx,4}=sparse(mask2);
                end
app.ImagesListBox.UserData=list;
app.ImagesListBoxValueChanged(app);
            catch 
            end

        end

        % Value changed function: ZoomType
        function ZoomTypeValueChanged(app, event)
haxes1=evalin('base', 'haxes1'); haxes2=evalin('base', 'haxes2'); 
if strcmp(app.ZoomType.Value,'+')
    if diff(ylim(haxes1))<diff(ylim(haxes2))
        haxes2.XLim=haxes1.XLim; haxes2.YLim=haxes1.YLim;
    else
        haxes1.XLim=haxes2.XLim; haxes1.YLim=haxes2.YLim;
    end
else
set(haxes1,'YLimMode','auto','XLimMode','auto')
set(haxes2,'YLimMode','auto','XLimMode','auto')
end
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
 list=app.ImagesListBox.UserData;
 path=app.Label.Text;

    
 Area=list(:,1);
 for i=1:size(list,1)
     ROI=full(list{i,2});
roi=full(list{i,4}) & ROI;
ROI=bwarea(ROI);
roi=bwarea(roi);
Area(i,2:4)=num2cell([roi,ROI,roi*100/ROI]);
 end
 Area=cell2table(Area); 
 Area.Properties.VariableNames={'ImageName','ColorArea','TissueArea','Percent'};

 writetable(Area,[path filesep 'ColorSegResults.xlsx'])
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.LoadimagefolderButton = uibutton(app.UIFigure, 'push');
            app.LoadimagefolderButton.ButtonPushedFcn = createCallbackFcn(app, @LoadimagefolderButtonPushed, true);
            app.LoadimagefolderButton.Text = {'Load'; 'image'; 'folder'};
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.ImagesListBox = uilistbox(app.UIFigure);
            app.ImagesListBox.Items = {''};
            app.ImagesListBox.ValueChangedFcn = createCallbackFcn(app, @ImagesListBoxValueChanged, true);
            app.ImagesListBox.Value = '';
            app.ScaleSpinnerLabel = uilabel(app.UIFigure);
            app.ScaleSpinnerLabel.Text = 'Scale';
            app.ScaleSpinner = uispinner(app.UIFigure);
            app.ScaleSpinner.Step = 0.05;
            app.ScaleSpinner.Limits = [0.001 1];
            app.ScaleSpinner.Value = 1;
            app.ClustersSpinnerLabel = uilabel(app.UIFigure);
            app.ClustersSpinnerLabel.Text = 'Clusters';
            app.ClustersSpinner = uispinner(app.UIFigure);
            app.ClustersSpinner.Limits = [2 5];
            app.ClustersSpinner.ValueChangedFcn = createCallbackFcn(app, @ClustersSpinnerValueChanged, true);
            app.ClustersSpinner.Value = 3;
            app.AnalysisButton = uibutton(app.UIFigure, 'push');
            app.AnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @AnalysisButtonPushed, true);
            app.AnalysisButton.Text = 'Analysis';
            app.ClrclustSpinnerLabel = uilabel(app.UIFigure);
            app.ClrclustSpinnerLabel.Text = 'Clr clust';
            app.ClrclustSpinner = uispinner(app.UIFigure);
            app.ClrclustSpinner.Limits = [1 3];
            app.ClrclustSpinner.Value = 1;
            app.ImagesListBoxLabel = uilabel(app.UIFigure);
            app.ImagesListBoxLabel.Text = 'Images';
            app.ProtocolDropDownLabel = uilabel(app.UIFigure);
            app.ProtocolDropDownLabel.Text = 'Protocol';
            app.ProtocolDropDown = uidropdown(app.UIFigure);
            app.ProtocolDropDown.Items = {'Picrosirius Red', 'TBD'};
            app.ProtocolDropDown.Value = 'Picrosirius Red';
            app.ToanalyzeDropDownLabel = uilabel(app.UIFigure);
            app.ToanalyzeDropDownLabel.Text = 'To analyze';
            app.ToanalyzeDropDown = uidropdown(app.UIFigure);
            app.ToanalyzeDropDown.Items = {'All', 'Selected', 'Selected onwards'};
            app.ToanalyzeDropDown.Value = 'All';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.MinSpinnerLabel = uilabel(app.UIFigure);
            app.MinSpinnerLabel.Text = 'Min';
            app.MinSpinner = uispinner(app.UIFigure);
            app.MinSpinner.Limits = [10 1000];
            app.MinSpinner.ValueChangedFcn = createCallbackFcn(app, @MinSpinnerValueChanged, true);
            app.MinSpinner.Value = 10;
            app.MaxSpinnerLabel = uilabel(app.UIFigure);
            app.MaxSpinnerLabel.Text = 'Max';
            app.MaxSpinner = uispinner(app.UIFigure);
            app.MaxSpinner.Limits = [10 1000];
            app.MaxSpinner.ValueChangedFcn = createCallbackFcn(app, @MaxSpinnerValueChanged, true);
            app.MaxSpinner.Value = 100;
            app.dilatevesCheckBox = uicheckbox(app.UIFigure);
            app.dilatevesCheckBox.ValueChangedFcn = createCallbackFcn(app, @dilatevesCheckBoxValueChanged, true);
            app.dilatevesCheckBox.Text = 'dilate-ves';
            app.ROItoShow = uiswitch(app.UIFigure, 'slider');
            app.ROItoShow.Items = {'In', 'Out'};
            app.ROItoShow.ValueChangedFcn = createCallbackFcn(app, @ROItoShowValueChanged, true);
            app.ROItoShow.Value = 'In';
            app.EditButtonGroup = uibuttongroup(app.UIFigure);
            app.EditButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @EditButtonGroupSelectionChanged, true);
            app.EditButtonGroup.Title = 'Edit';
            app.ROIButton = uiradiobutton(app.EditButtonGroup);
            app.ROIButton.Text = 'ROI';
            app.ROIButton.Value = true;
            app.ClrButton = uiradiobutton(app.EditButtonGroup);
            app.ClrButton.Text = 'Clr';
            app.EditButton = uibutton(app.UIFigure, 'push');
            app.EditButton.ButtonPushedFcn = createCallbackFcn(app, @EditButtonPushed, true);
            app.EditButton.Text = 'Edit';
            app.EditType = uiswitch(app.UIFigure, 'slider');
            app.EditType.Items = {'-', '+'};
            app.EditType.ValueChangedFcn = createCallbackFcn(app, @EditTypeValueChanged, true);
            app.EditType.Value = '-';
            app.ZoomType = uiswitch(app.UIFigure, 'slider');
            app.ZoomType.Items = {'-', '+'};
            app.ZoomType.ValueChangedFcn = createCallbackFcn(app, @ZoomTypeValueChanged, true);
            app.ZoomType.Value = '-';
            app.zoomLabel = uilabel(app.UIFigure);
            app.zoomLabel.Text = '==zoom';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ColorSeg

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end